classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        triaxialExperimentResults       matlab.ui.Figure
        tabGroup                        matlab.ui.container.TabGroup
        experimentsAndRockDataTab       matlab.ui.container.Tab
        experimentOverviewPanel         matlab.ui.container.Panel
        experimentsOverviewTable        matlab.ui.control.Table
        SelectedExperimentLabel         matlab.ui.control.Label
        selectExperimentPrimary         matlab.ui.control.DropDown
        rockDataPanel                   matlab.ui.container.Panel
        rockDataTable                   matlab.ui.control.Table
        dataAndOutliersTab              matlab.ui.container.Tab
        dataAndWeightPanel              matlab.ui.container.Panel
        graphOverview                   matlab.ui.control.UIAxes
        graphWeight                     matlab.ui.control.UIAxes
        fluidFlowAndDeformationPanel    matlab.ui.container.Panel
        graphFluidFlow                  matlab.ui.control.UIAxes
        graphDeformation                matlab.ui.control.UIAxes
        timelogTab                      matlab.ui.container.Tab
        permeabilityResultsPanel        matlab.ui.container.Panel
        graphTimelogTab                 matlab.ui.control.UIAxes
        DisplayonrightaxisLabel         matlab.ui.control.Label
        y2AxisTimelogTab                matlab.ui.control.DropDown
        clearEventsButton               matlab.ui.control.Button
        timelogPanel                    matlab.ui.container.Panel
        timelogTableActual              matlab.ui.control.Table
        timelogTablePre                 matlab.ui.control.Table
        timelogTablePost                matlab.ui.control.Table
        TimestepminLabel_2              matlab.ui.control.Label
        selectTimestepPrimary           matlab.ui.control.DropDown
        DisplayonleftaxisLabel          matlab.ui.control.Label
        y1AxisTimelogTab                matlab.ui.control.DropDown
        btnExportGraphs                 matlab.ui.control.Button
        comparingResultsTab             matlab.ui.container.Tab
        comparePanel                    matlab.ui.container.Panel
        graphPrimaryComparisonTab       matlab.ui.control.UIAxes
        graphSecondaryComparisonTab     matlab.ui.control.UIAxes
        selectExperimentSecondary       matlab.ui.control.DropDown
        ComparetoExperimentNoLabel      matlab.ui.control.Label
        TimestepminLabel                matlab.ui.control.Label
        selectTimestepSecondary         matlab.ui.control.DropDown
        DisplayonrightaxisLabel_2       matlab.ui.control.Label
        y2AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        DisplayonrightaxisLabel_3       matlab.ui.control.Label
        y2AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        DisplayonleftaxisDropDownLabel  matlab.ui.control.Label
        y1AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        DisplayonleftaxisDropDown_2Label  matlab.ui.control.Label
        y1AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        rockDataComparePagePrimary      matlab.ui.control.Table
        rockDataComparePageSecondary    matlab.ui.control.Table
        ExportData2Excel                matlab.ui.control.Button
        compare2Tab                     matlab.ui.container.Tab
        graphPrimaryComparisonTab2      matlab.ui.control.UIAxes
        graphSecondaryComparisonTab2    matlab.ui.control.UIAxes
        LeftAxisDropDownLabel           matlab.ui.control.Label
        y1AxisPrimaryComparisonTab2     matlab.ui.control.DropDown
        xAxisDropDown_2Label            matlab.ui.control.Label
        xAxisPrimaryComparisonTab2      matlab.ui.control.DropDown
        RightAxisDropDownLabel          matlab.ui.control.Label
        y2AxisPrimaryComparisonTab2     matlab.ui.control.DropDown
        LeftAxisDropDown_2Label         matlab.ui.control.Label
        y1AxisSecondaryComparisonTab2   matlab.ui.control.DropDown
        xAxisDropDownLabel              matlab.ui.control.Label
        xAxisSecondaryComparisonTab2    matlab.ui.control.DropDown
        RightAxisDropDown_2Label        matlab.ui.control.Label
        y2AxisSecondaryComparisonTab2   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
         db % Database connection
         
         %variables for primary experiment loaded
         experimentNoPrimary
         
         %variables for secondary experiment loaded
         experimentNoSecondary
         
         Analytics_timeStep %new Table (chosen timestep)
         flowMassOutliers %flowMass outliers graph
         plotCorrectedGraphWeight %corrected flowMass graph 
         
         loadingProgBar % Progressbar while loading or calculating
         
         %variables for dropdown menus
         y1PlotTimelogTab
         y2PlotTimelogTab
         y1PlotPrimaryComparisonTab
         y2PlotPrimaryComparisonTab
         y1PlotSecondaryComparisonTab
         y2PlotSecondaryComparisonTab
         y1PlotPrimaryComparisonTab2
         y2PlotPrimaryComparisonTab2
         y1PlotSecondaryComparisonTab2
         y2PlotSecondaryComparisonTab2
         xPlotPrimaryComparisonTab2
         xPlotSecondaryComparisonTab2
         
         %variables containing the graphs
         strainSensorsMeanGraph
         fluidPressureGraph
         hydraulicCylinderGraph
         confiningPressureGraph
         flowMassGraph
         flowMassDiffGraph
         strainSensor1MeanDeltaGraph
         strainSensor2MeanDeltaGraph
         
         %creating a list of all graphs
         listGraphs
         
         plotProperties
         
         timelog %timelog table
         
         %permeability_timeStep %permeability of selected timestep
         plotline %Line plot to mark timelog events
         plottext %number of timelog event
    end
    
    methods (Access = private)
        
        function result = export2csv(app, experimentNo, xValue, y1Value, y2Value, timestep)
        %Function used for exporting graph data to an xls-file.
        
            if nargin < 6 || nargin > 6
                error([class(app), ' - ', 'Not enough or too many input arguments.']);
            end
            
            % Stop function execution when no value is submitted!
            if (isempty(xValue) || strcmp(xValue, 'none')) && (isempty(y1Value) || strcmp(y1Value, 'none')) && (isempty(y2Value) || strcmp(y2Value, 'none'))
                return;
            else
            
                try
                    [file, path] = uiputfile('*.csv','File Selection','export.csv'); %Set save-path
                    
                    graphData = app.db.getGraphData(experimentNo, xValue, y1Value, y2Value, timestep); %Get graph dataset
                    
                    writetimetable(graphData.dataset,[path file], 'FileType', 'text') %Write table to csv file
                    
                    uialert(app.triaxialExperimentResults, 'File export succeeded', 'File Export Succeeded', 'Icon', 'success');
                    result = true;
                catch E
                    warning()
                    uialert(app.triaxialExperimentResults, ['File export failed. ', E.message], 'Warning', 'Icon', 'warning');
                    result = false;
                    
                end
                
            end    
                
        end
        
        function result = exportGraphs(app)
            
            % Set default values
            exportResolution = 600;
            validExtensions = ["png"; "eps"; "pdf"];
            fExtensionList = [];
            
            for extension = validExtensions
                fExtensionList = [fExtensionList; strcat('*.', extension)];
            end
            
            try
                [fName, fPath] = uiputfile(fExtensionList);
                
                % Show please wait bar
                titleText = 'Triaxial Experiments Analysis Tool';
                messageText = strcat('Exporting, please wait...');
                loadingProgBar_2 = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                
                % Check if set file is valid
                if isequal(fPath, 0) || isequal(fName, 0)
                    error([class(app), ' - ', 'No file/folder for export selected.'])
                end
                
                [~, fName, fExtension] = fileparts(fName);
                
                % Check if extension is part of list of valid extensions
                if ~ismember(extractAfter(fExtension, 1), validExtensions)
                    error([class(app), ' - ', strcat('Invalid file extentsion: ', fExtension)])
                end
                
                % Iterate over all graphs
                preText = [];
                for graph = app.listGraphs
                    graphTitle = graph.Title.String;
                    
                    % If graph has no title, axis labels will be used
                    if isempty(graphTitle) ||isequal(graphTitle, '') || isequal(graphTitle, 'Title')
                        xLabel = extractBefore(graph.XLabel.String, " ");
                        yLabel = extractBefore(graph.YLabel.String, " ");
                        if ~isempty(xLabel) && ~isempty(yLabel)
                            graphTitle = strcat(xLabel, "-", yLabel);
                        else
                            preText = [preText strcat("   ", " ...empty graph ignored")];
                            continue;
                        end
                    end


                    filename = strcat(fName, '_', lower(graphTitle), fExtension);
                    exportFile = fullfile(fPath, filename);
                    
                    nowText = strcat("   ", filename," ...in progress");
                    messageText = ["Exporting, please wait..." preText nowText];
                    loadingProgBar_2 = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');                    
                    
                    exportgraphics(graph, exportFile, 'Resolution', exportResolution, 'BackgroundColor','none')
                    
                    preText = [preText strcat("   ", filename," ...done")];
                end
                
                uialert(app.triaxialExperimentResults, ["Graph export successful." " " preText], 'Export successful', 'Icon', 'success');
                result = true;
                return;
            
            catch E
                close(loadingProgBar_2);
                uialert(app.triaxialExperimentResults, ['An error occured while graph export: ', ...
                    E.message], 'error');
                result = false;
                return;
            end
        end
        
        
        function result = setGraphDataSet(app, experimentNo, xValue, y1Value, y2Value, timestep, graphVar, graphClear)
        %Function to plot graphs with given datasets into given graphs
        %depending on the handed over selected dataset (selected via
        %dropdown).
            
            if nargin < 7 || nargin > 8
                error([class(app), ' - ', 'Not enough or too many input arguments.']);
            elseif nargin == 7
                graphClear = false;
            end
            
            % Stop function execution when no value is submitted!
            if (isempty(xValue) || strcmp(xValue, 'none')) && (isempty(y1Value) || strcmp(y1Value, 'none')) && (isempty(y2Value) || strcmp(y2Value, 'none'))
                return;
            else    
            
                try
                    %Show please wait bar
                    titleText = 'Triaxial Experiments Analysis Tool';
                    messageText = strcat('Loading, please wait...');
                    loadingProgBar_2 = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                    
                    graphData = app.db.getGraphData(experimentNo, xValue, y1Value, y2Value, timestep);
                    
                    
                    %Set plot marker to none as default
                    y1Marker = 'none';
                    y2Marker = 'none';
                    
                    %Clear graph
                    if graphClear
                       cla(graphVar,'reset');
                    end
                    
                    %Replace NaN by [] to have a plotted line
                    graphDataNoNaN = graphData;
                    graphDataNoNaN.dataset = fillmissing(graphData.dataset, 'linear');
                    
                    if ~isempty(y1Value) && ~strcmp(y1Value, 'none')
                        
                        yyaxis(graphVar, 'left');
                        switch  xValue  
                            case 'runtime'
                                plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y1, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y1.legend);
                                
                            otherwise
                                plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y1, 'DisplayName', graphDataNoNaN.y1.legend);
                        end 
                        
                        plotVar.Color = app.plotProperties.lineColor('left');
                        plotVar.LineStyle = app.plotProperties.lineStyle('left');
                        plotVar.Marker = y1Marker;
                        ylabel(graphVar, graphDataNoNaN.y1.legend);
                        xlabel(graphVar, graphDataNoNaN.x.legend);
                        graphVar.YColor = app.plotProperties.lineColor('left');
                        graphVar.BackgroundColor='white';
                        lgd = legend(graphVar,'-DynamicLegend');
                        lgd.Title.String = [regexprep(app.db.getMetaData(experimentNo).short,'_','\\_'), ' (#',  int2str(experimentNo),')'];
                        hold (graphVar, 'on') 
                    end
                    
                    if ~isempty(y2Value) && ~strcmp(y2Value, 'none')
                        
                        yyaxis(graphVar, 'right');
                        switch  xValue  
                            case 'runtime'
                                plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y2, 'DurationTickFormat', graphDataNoNaN.x.unit, 'DisplayName', graphDataNoNaN.y2.legend);
                                
                            otherwise
                                plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y2, 'DisplayName', graphDataNoNaN.y2.legend);
                        end
                        
                        plotVar.Color = app.plotProperties.lineColor('right');
                        plotVar.LineStyle = app.plotProperties.lineStyle('right');
                        plotVar.Marker = y2Marker;
                        ylabel(graphVar, graphDataNoNaN.y2.legend);
                        xlabel(graphVar, graphDataNoNaN.x.legend);
                        graphVar.YColor = app.plotProperties.lineColor('right');
                        graphVar.BackgroundColor='white';
                        lgd = legend(graphVar, '-DynamicLegend');
                        lgd.Title.String = [regexprep(app.db.getMetaData(experimentNo).short,'_','\\_'), ' (#',  int2str(experimentNo),')'];
                        hold (graphVar, 'on')   
                    end
                    
                    %Check if one of the datasets is smaller then the other
                    if sum(strcmp('y1',graphData.dataset.Properties.VariableNames))
                        if length(graphData.y1.data) < length(graphData.x.data)
                            
                            yyaxis(graphVar, 'left');
                            switch  xValue  
                                case 'runtime'
                                    plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y1, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y1.legend);
                                    
                                otherwise
                                    plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y1, 'DisplayName', graphData.y1.legend);
                            end 
                            
                            plotVar.Color = app.plotProperties.lineColor('left');
                            plotVar.LineStyle = 'none';
                            plotVar.Marker = app.plotProperties.marker('left');
                        end
                    end
                    
                    if sum(strcmp('y2',graphData.dataset.Properties.VariableNames))
                        if length(graphData.y2.data) < length(graphData.x.data)
                            
                            yyaxis(graphVar, 'right');
                            switch  xValue  
                                case 'runtime'
                                    plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y2, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y2.legend);
                                    
                                otherwise
                                    plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y2);
                            end 
                            
                            plotVar.Color = app.plotProperties.lineColor('right');
                            plotVar.LineStyle = 'none';
                            plotVar.Marker = app.plotProperties.marker('right');
                            
                        end
                    end
                    
                    result = true;
                    close(loadingProgBar_2);
                    
                catch E
                    close(loadingProgBar_2);
                    uialert(app.triaxialExperimentResults, ['An error occured while loading a new dataset for the selected graph: ' ...
                        '#', int2str(experimentNo), '; ', ...
                        'x: ', xValue, '; ', ...
                        'y1: ', y1Value, '; ', ...
                        'y1: ', y2Value, '; ',...
                        int2str(timestep),  ' min; ', E.message], 'warning', 'Icon', 'warning');
                    result = false;
                    return;
                end
            end
            
        end
        
        function showTimelogTick(app, graphHandle, tableHandle, row)
        %Showing timelog events in a given graph. Handle for the graph and the timelog table have to be input.
        %The row containing the information is an input parameter as well.
        
            yLimit = graphHandle.YLim; %add row number in plot
            
            isChecked = tableHandle.Data{row, 4}; %finds checked boxes
            
            %check if an event is checked or unchecked
            if isChecked
                runtimeEvent = tableHandle.Data{row, 3}; %find runtime
                
                %alternating colors and position of numbering
                if mod(row,2)
                    color = 'green';
                    position = 0.9;
                else
                    color = 'magenta';
                    position = 0.85;
                end
                
                %plot vertical line
                app.plotline(row) = plot(graphHandle, [runtimeEvent runtimeEvent], [yLimit(1,1) yLimit(1,2)], 'm-', 'HandleVisibility', 'off', 'Color', color);
                
                %plot row number
                txt = num2str(row);
                v = ones(size(runtimeEvent)) * (yLimit(1,2) * position); %location of number y axis (80% of maximum y-axes)
                app.plottext(row) = text(graphHandle, runtimeEvent, v, txt, 'FontSize', 14, 'FontWeight', 'bold');
                
            else
                %Try to delete existing rows
                try
                    delete(app.plotline(row))
                    delete(app.plottext(row))
                catch
                    
                end
            
            end 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Function that is called after gui components are initialized. It
        %develops the connection to the database and loads a list of all
        %available experiments.
        %All dropdown menus will be initialized and filled with data,
        %especcialy the dropdown menu to choose an experiment.
        
        %Load submodule water-properties
        try
            thisPath = fileparts(which([mfilename('class'),'.mlapp'])); %Get the folder of the actual class file
            submodulePath = [thisPath, '/classes'];
            addpath(submodulePath) %Add sobmodule path
        catch
            error([class(app), ' - ', 'Error while loading subclasses']);
        end
        
        import MeridDB.*
            
            app.triaxialExperimentResults.WindowState = 'maximized'; %Maximize main window 
            
            %Show please wait bar
            titleText = 'Triaxial Experiments Analysis Tool';
            messageText = strcat('Loading, please wait...');
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
        
            %Initialize property-table for plots/graphs
            app.plotProperties = table('Size', [2,3], 'VariableTypes', {'string', 'string', 'string'}, 'RowNames', {'left';'right'}, 'VariableNames',{'lineColor','marker','lineStyle'});
            app.plotProperties.lineColor('left') = '#0072BD';
            app.plotProperties.marker('left') = 'x';
            app.plotProperties.lineStyle('left') = '-';
            app.plotProperties.lineColor('right') = '#D95319';
            app.plotProperties.marker('right') = 'o';
            app.plotProperties.lineStyle('right') = '-.';
            
            %Initialize database
            try
                app.db = TriaxTestHandler;
            catch E
                close(app.loadingProgBar);
                uialert(app.triaxialExperimentResults, ['Initialization of connection to database failed. Please restart this tool! (', E.message, ')'], 'DB Connection');
                return;
            end
            
            %Request a list of all experiments and print out in table
                %Request list of experiments
                experimentOverview = app.db.getExperimentOverview;
                experimentOverview.experimentNo = single(experimentOverview.experimentNo); %Convert experiment number to single
                
                %Format datetime-variables
                experimentOverview.timeStart.Format = 'dd.MM.uu HH:mm';
                experimentOverview.timeEnd.Format = 'dd.MM.uu HH:mm';
                
                %Output
                app.experimentsOverviewTable.Data = experimentOverview;
                app.experimentsOverviewTable.ColumnName = {'#','Short','Begin','End'};
            
            
            %Initialization of dropdowns
                %Experiment-selection dropdown
                dropDownListText = ['- no selection -'; strcat(string(experimentOverview.experimentNo), ' - ', string(experimentOverview.short))];
                dropDownListData = [0; experimentOverview.experimentNo];
                app.selectExperimentPrimary.Items = dropDownListText;
                app.selectExperimentPrimary.ItemsData = dropDownListData;
                app.selectExperimentPrimary.Tooltip='The permeability will be calculated using a default value of 5 minutes. To select a different timestep, use the Dropdown on the "Results" page.';
                app.xAxisPrimaryComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                app.xAxisSecondaryComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                
                app.selectExperimentSecondary.Items = app.selectExperimentPrimary.Items;
                app.selectExperimentSecondary.ItemsData = app.selectExperimentPrimary.ItemsData;
            
                %Permeability timestep-selection dropdown
                app.selectTimestepPrimary.Items = ['- no selection -'; string([1; 2; 5; 10; 15; 20; 25; 30; 45; 60])];
                app.selectTimestepPrimary.ItemsData = [0; 1; 2; 5; 10; 15; 20; 25; 30; 45; 60];
                app.selectTimestepSecondary.Items = app.selectTimestepPrimary.Items;
                app.selectTimestepSecondary.ItemsData = app.selectTimestepPrimary.ItemsData;
            
                %Dataset-selection dropdown for graphs
                dropDownListText = horzcat('- no selection -', app.db.getLabelList.values);
                dropDownListData = horzcat('none', app.db.getLabelList.keys);                
                
                app.y2AxisPrimaryComparisonTab.Items = dropDownListText;
                app.y2AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                app.y2AxisSecondaryComparisonTab.Items = dropDownListText;
                app.y2AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.y2AxisTimelogTab.Items = dropDownListText;
                app.y2AxisTimelogTab.ItemsData = dropDownListData;
                app.y1AxisTimelogTab.Items = dropDownListText;
                app.y1AxisTimelogTab.ItemsData = dropDownListData;
                app.y1AxisPrimaryComparisonTab.Items = dropDownListText;
                app.y1AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                app.y1AxisSecondaryComparisonTab.Items = dropDownListText;
                app.y1AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.y1AxisPrimaryComparisonTab2.Items = dropDownListText;
                app.y1AxisPrimaryComparisonTab2.ItemsData = dropDownListData;
                app.y2AxisPrimaryComparisonTab2.Items = dropDownListText;
                app.y2AxisPrimaryComparisonTab2.ItemsData = dropDownListData;
                app.xAxisPrimaryComparisonTab2.Items = dropDownListText;
                app.xAxisPrimaryComparisonTab2.ItemsData = dropDownListData;
                app.y1AxisSecondaryComparisonTab2.Items = dropDownListText;
                app.y1AxisSecondaryComparisonTab2.ItemsData = dropDownListData;
                app.y2AxisSecondaryComparisonTab2.Items = dropDownListText;
                app.y2AxisSecondaryComparisonTab2.ItemsData = dropDownListData;
                app.xAxisSecondaryComparisonTab2.Items = dropDownListText;
                app.xAxisSecondaryComparisonTab2.ItemsData = dropDownListData;
            
                % Initialize list of graphs
                app.listGraphs = [app.graphOverview, app.graphWeight, app.graphFluidFlow, app.graphDeformation, app.graphTimelogTab, app.graphPrimaryComparisonTab, app.graphSecondaryComparisonTab, app.graphPrimaryComparisonTab2, app.graphSecondaryComparisonTab2];
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %This part should be put into another function and should be
            %handeld by the tabs and ther children
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %panel visibility is off for all panels before calculation 
            app.rockDataPanel.Visible = 'off';
            app.dataAndWeightPanel.Visible = 'off';
            app.fluidFlowAndDeformationPanel.Visible = 'off';
            app.permeabilityResultsPanel.Visible = 'off';
            app.graphPrimaryComparisonTab.Visible = 'off';
            app.comparePanel.Visible = 'off';
            
            close(app.loadingProgBar);
    
        end

        % Callback function
        function displayOutliersWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display outliers for flowMass
            checkboxValue = app.displayOutliersWeight.Value;
            
            if checkboxValue == 1 
                TF = isoutlier(app.experimentsDataPrimary.flowMassDiff, 'mean');
                x = app.experimentsDataPrimary.runtime;
                y = app.experimentsDataPrimary.flowMassDiff;
                
                yyaxis(app.graphWeight, 'right')
                app.flowMassOutliers = plot(app.graphWeight, x(TF), y(TF), 'rx', 'HandleVisibility', 'off');
            
            else
                delete(app.flowMassOutliers)
                
            end
         
        end

        % Callback function
        function correctedGraphWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display corrected flowMass curve     
            checkboxValue = event.Value;
                
            if checkboxValue == 1
                %delete current flowMass diff graph
                delete(app.flowMassDiffGraph)
                delete(app.flowMassOutliers)
                app.displayOutliersWeight.Value=0;
                x = app.permeabilityDataPrimary.runtime;
                y=app.permeabilityDataPrimary.flowMassDiff;
                yyaxis(app.graphWeight, 'right')
                app.plotCorrectedGraphWeight = plot(app.graphWeight, x, y, 'm-', 'HandleVisibility', 'off');
               
            else %delete plot if box is unchecked
                delete(app.plotCorrectedGraphWeight)
                %plot original flowMassDiff graph
                app.flowMassDiffGraph= app.changeGraphDataSet(...
                    'primary', ...
                    'flowMassDiff', ...
                    app.flowMassDiffGraph, ...
                    app.graphWeight, ...
                    'right','runtime');
                  
            end
        end

        % Value changed function: y1AxisTimelogTab, y2AxisTimelogTab
        function graphTimelogTabReload(app, event)
            
            y1Selection = app.y1AxisTimelogTab.Value;
            y2Selection = app.y2AxisTimelogTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphTimelogTab, true);
            
            %Respect ticks from the timelog
            if ~isempty(app.timelogTableActual.Data)
                for k = 1 : height(app.timelogTableActual.Data)
                    
                    %Check which timelog is ticked
                    if app.timelogTableActual.Data{k, 4}
                        app.showTimelogTick(app.graphTimelogTab, app.timelogTableActual, k);
                    end
                end
            end
        
        end

        % Value changed function: selectTimestepPrimary
        function primaryTimestepChange(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestepPrimary.Value == 0)
                uialert(app.triaxialExperimentResults,'No timestep was selected.','Warning', 'Icon', 'warning');
                return;
            end
            
            app.graphTimelogTabReload();
            app.graphComparisonTabReload_Primary();
            app.graphComparisonTab2Reload_Primary();
        end

        % Value changed function: selectExperimentPrimary
        function selectExperimentNoPrimary(app, event)
        
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentPrimary.Value;     
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) || value == 0  %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialExperimentResults, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoPrimary = value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNoPrimary));
            messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoPrimary));
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
            
            %Clear graphs and tables and timelog
            cla(app.graphWeight,'reset')
            cla(app.graphDeformation,'reset')
            cla(app.graphFluidFlow,'reset')
            cla(app.graphOverview,'reset')
            cla(app.graphTimelogTab, 'reset')
            cla(app.graphPrimaryComparisonTab, 'reset')
            
            %Reset timelog table
            %empty data in tables
            app.timelogTableActual.Data = [];
            app.timelogTablePre.Data = [];
            app.timelogTablePost.Data = [];
            %add comment
            app.timelogTableActual.ColumnName = {'No timelog available'};
            app.timelogTablePre.ColumnName = {'No timelog available'};
            app.timelogTablePost.ColumnName = {'No timelog available'};
            app.timelogTableActual.ColumnWidth = 'auto';
            app.timelogTablePre.ColumnWidth = 'auto';
            app.timelogTablePost.ColumnWidth = 'auto';
            %remove events button
            app.clearEventsButton.Enable = 'off';
            
            app.timelogTableActual.ColumnName = [];
            app.y2AxisTimelogTab.Value = 'none';
            app.y2AxisPrimaryComparisonTab.Value = 'none';
            
            %Check if the experiment is allready loaded
            if ~app.db.isExperimentLoaded(app.experimentNoPrimary)
                messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoPrimary), ' from database. Depending on the duration of the experiment this might take a while.');
                app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                if(~app.db.loadExperiment(app.experimentNoPrimary))
                    uialert(app.triaxialExperimentResults, 'Error loading experiments data.', 'Error');
                    close(app.loadingProgBar);
                    return;
                end
            end
            
            %Try to load metadata and specimendata from database und show them in a table
            try
                %Get metadata from database
                messageText = strcat('Loading metadata for experiment no. ', string(app.experimentNoPrimary));
                app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                metaData = app.db.getMetaData((app.experimentNoPrimary));
                
                %Check if metadata was submitted, otherwise exit function
                if (metaData == 0)
                    app.triaxialExperimentResults.Name = strcat('MERID Datainspector');
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No metadata for selected experiment found.', 'Warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                    
                else
                    app.triaxialExperimentResults.Name = ['MERID Datainspector - Experiment: ', metaData.short];

                end
 
                %Get specimen and rock data from database
                specimenData = app.db.getSpecimenData(app.experimentNoPrimary);
                
                %Check if specimen data was submitted, otherwise exit function
                if (specimenData == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No specimendata for selected experiment found.', 'warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                end
                
                %Write rockdata to table in GUI
                app.rockDataTable.RowName = []; %clear column property
                app.rockDataPanel.Title = ('Rock Data');
                app.rockDataTable.Data = specimenData.getDataTable;
                app.rockDataTable.ColumnName = {'Property','Value'};
                app.rockDataPanel.Visible = 'on';
                
                %Rock Data Compare Page                
                app.rockDataComparePagePrimary.Data = specimenData.getDataTable;
                
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading specimen- or metadata for the selected experiment.', 'warning', 'Icon', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Display all data';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
            
                  
            %GRAPH OVERVIEW containing all relevant measurements
            %setGraphDataSet(app, experimentNo, xValue, y1Value, y2Value, timestep, graphVar)
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'strainSensor', '', ...
                0, app.graphOverview, true);
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'confiningPressure', ...
                0, app.graphOverview);
                
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'hydrCylinderPressure', ...
                0, app.graphOverview); 
                                        
            title(app.graphOverview, 'Data Overview');

            %GRAPH FLUID FLOW containing fluid pressure
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'fluidPressure', '', ...
                0, app.graphFluidFlow, true); 
                    
            title(app.graphFluidFlow, 'Fluid Flow Data');
            
            %GRAPH WEIGHT containing flowMass and flowMass difference
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'flowMass', 'flowMassDiff', ...
                0, app.graphWeight, true);
                    
            title(app.graphWeight, 'Weigth and Weight Difference'); 
            
            %GRAPH DEFORMATION
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'strainSensor', 'strainSensor1', ...
                0, app.graphDeformation, true);
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'strainSensor2', ...
                0, app.graphDeformation);
            
            title(app.graphDeformation, 'Deformation and Deviation of Sensors');

            
            %make all panels visible
            app.dataAndOutliersTab.HandleVisibility = 'on';
            app.dataAndWeightPanel.Visible = 'on';
            app.fluidFlowAndDeformationPanel.Visible = 'on';
            app.permeabilityResultsPanel.Visible = 'on';
            app.comparePanel.Visible = 'on';
            
            %Plot y1-axis in graph on timetable tab
            %Plot Permeability
            app.selectTimestepPrimary.Value = 5;                
            app.y1AxisTimelogTab.Value = 'permeabilityCoeff';
            app.y1AxisPrimaryComparisonTab.Value = 'permeabilityCoeff';
            
            app.graphTimelogTabReload();
            app.graphTimelogTab.Visible='on'; 
            
            app.graphComparisonTabReload_Primary();
            app.graphPrimaryComparisonTab.Visible='on'; 
            
            %set TIMELOG data in table 
%             try
                if ~isempty(app.db.getTimelog(app.experimentNoPrimary)) 
                    app.clearEventsButton.Enable = 'on';
                    
                    %use any table that has not been retimed ... to find matching runtimes
                    %retime table as all timelog entries have format
                    %HH:MM
                    dataTableTimelog = retime(app.db.getPressureData(app.experimentNoPrimary),'minutely','linear');
                    
                    %Only use timelog entries that match the start and
                    %end times of the data table-- otherwise runtime
                    %cannot be added as array size does not match
                    timerangeTable = timerange(dataTableTimelog.datetime(1), dataTableTimelog.datetime(end), 'closed');
                    app.timelog = app.db.getTimelog(app.experimentNoPrimary); %create timetable
                    app.timelog = app.timelog(timerangeTable,:);
                    app.timelog = timetable2table(app.timelog); %neccessary otherwise markers are not found
                    
                    %Find markers for time log events and add runtime to timelog
                    eventTime = app.timelog.time;
                    markers = dataTableTimelog(eventTime,:); %finds all rows of table corresponding to the datetime 
                    app.timelog.runtime = (markers.runtime); %add runtime to timelog
                    app.timelog.runtime.Format = ('hh:mm');
                    
                    %delete columns and sort table
                    app.timelog.retrospective = [];
                    app.timelog = sortrows(app.timelog, 'time', 'ascend'); %sort timelog table
                    app.timelog.time.Format = 'dd-MM-yyyy HH:mm';
                    app.timelog.selectEvents = false(size(app.timelog.description)); %add checkbox column
                    
                    %set row name, colum name and data in main UITable
                    app.timelogTableActual.Data = app.timelog;
                    app.timelogTableActual.ColumnName = {'Time','Description','Runtime','Select Events'};
                    app.timelogTableActual.RowName = 'numbered';
                       
%                     %FIND entries outside of experiment timerange
%                     timelogRemain = app.dbMetadataPrimary.timeLog;     
               
                     
%                 if app.dbMetadataPrimary.timeStart>timelogRemain.time(1) %all entries before start time
%                               
%                           start_of_entries=timelogRemain.time(1);
%                           TR1=timerange(start_of_entries,timelogRemain.time(1),'closed'); %half open intervall
%                           
%                           timelogPre=table2timetable(timelogRemain);
%                           timelogPre=timelogPre(TR1,:); %find all rows before start
%                           timelogPre=timetable2table(timelogPre); %data for UITable must be table
%                           timelogPre.logId=[];
%                           timelogPre.experimentNo=[];
%                           timelogPre.retrospective=[];
%                           
%                           %add 'not in range' to runtime and select events column
%                           none=cell(size(timelogPre.time));
%                           none{1,1}='not in range';
%                           timelogPre.runtime=none;
%                           timelogPre.selectEvents=none;
%                           
%                 else
%                      
%                           col1={'No entries before start of the experiment'};
%                           timelogPre=table(col1);
%                           app.timelogTablePre.ColumnWidth='auto';
%                           app.timelogTablePre.ColumnName={'No Timelog'};
%                 end
%                 
%                 if app.dbMetadataPrimary.timeEnd<timelogRemain.time(end) %all entries after end of experiment
%                            TR2=timerange(app.dbMetadataPrimary.timeEnd,timelogRemain.time(end),'closed');  
%                           
%                           timelogPost=table2timetable(timelogRemain);
%                           timelogPost=timelogPost(TR2,:); %find all rows before start
%                           timelogPost=timetable2table(timelogPost); %data for UITable must be table
%                           timelogPost.logId=[];
%                           timelogPost.experimentNo=[];
%                           timelogPost.retrospective=[];
%                           
%                           %add 'not in range' to runtime and select events column
%                           none=cell(size(timelogPost.time));
%                           none{1,1}='not in range';
%                           timelogPost.runtime=none;
%                           timelogPost.selectEvents=none;
%                 else 
%                           col1={'No entries after end of the experiment'};
%                           timelogPost=table(col1);
%                           app.timelogTablePre.ColumnWidth='auto';
%                           app.timelogTablePre.ColumnName={'No Timelog'};
%                 end 
                       
                          app.timelogTablePre.RowName='numbered';
                          app.timelogTablePre.ColumnName={'Time','Description','Runtime','Select Events'};
                          app.timelogTablePost.RowName='numbered';
                          app.timelogTablePost.ColumnName={'Time','Description','Runtime','Select Events'};


                        % set data in pre and post timelog tables
%                         app.timelogTablePre.Data=timelogPre;
%                         app.timelogTablePost.Data=timelogPost;
                     
                    end
%                 catch
%                     uialert(app.triaxialExperimentResults, 'An error occured handling the timelog. No timelog for this experiment will be shown.', 'Warning', 'Icon', 'warning');
%                 end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar); 

        end

        % Value changed function: selectExperimentSecondary
        function selectExperimentNoSecondary(app, event)
            
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentSecondary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialExperimentResults, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoSecondary = value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNoSecondary));
            %Check if the experiment is allready loaded
            if ~app.db.isExperimentLoaded(app.experimentNoSecondary)
                messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoSecondary), ' from database. Depending on the duration of the experiment this might take a while.');
                app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                
                if(~app.db.loadExperiment(app.experimentNoSecondary))
                    uialert(app.triaxialExperimentResults, 'Error loading experiments data.', 'Error');
                    close(app.loadingProgBar);
                    return;
                end
                
            end
            
            try
                %Get metadata from database
                messageText = strcat('Loading metadata for experiment no. ', string(app.experimentNoSecondary));
                app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                metaData = app.db.getMetaData((app.experimentNoSecondary));
                
                %Check if metadata was submitted, otherwise exit function
                if (metaData == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No metadata for selected experiment found.', 'warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                end
 
                %Get specimen and rock data from database
                specimenData = app.db.getSpecimenData(app.experimentNoSecondary);
                
                %Check if specimen data was submitted, otherwise exit function
                if (specimenData == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No specimendata for selected experiment found.', 'warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                end

            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading specimen- oder metadata for the selected experiment.', 'warning', 'Icon', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Rock Data Compare Page
            
            %Rock Data Compare Page                
            app.rockDataComparePageSecondary.Data = specimenData.getDataTable;


            %Show timestep selection after data sucesfully loaded            
            app.selectTimestepSecondary.Enable = 'on';
            app.selectTimestepSecondary.Value = 5;
            app.y1AxisSecondaryComparisonTab.Enable = 'on';
            app.y1AxisSecondaryComparisonTab.Value = 'permeabilityCoeff';
            app.y2AxisSecondaryComparisonTab.Enable = 'on';
            
            app.xAxisSecondaryComparisonTab2.Enable = 'on';
            app.y1AxisSecondaryComparisonTab2.Enable = 'on';
            app.y2AxisSecondaryComparisonTab2.Enable = 'on';
            
            app.graphComparisonTabReload_Secondary;
            app.graphComparisonTab2Reload_Secondary;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);
        end

        % Callback function
        function selectTimestepSecondaryValueChanged(app, event)
            
            app.graphSecondaryComparisonTab; 
        end

        % Cell edit callback: timelogTableActual
        function timelogTicksChange(app, event)
            indices = event.Indices;   %row and column numer of event    
             
            app.showTimelogTick(app.graphTimelogTab, app.timelogTableActual, indices(1));
        end

        % Button pushed function: clearEventsButton
        function clearTimelogTicks(app, event)
            %uncheck select events column
            app.timelogTableActual.Data{:,4} = 0;
            
            %Reload graph
            app.graphTimelogTabReload;

            
        end

        % Value changed function: y1AxisPrimaryComparisonTab, 
        % y2AxisPrimaryComparisonTab
        function graphComparisonTabReload_Primary(app, event)
           
            y1Selection = app.y1AxisPrimaryComparisonTab.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphPrimaryComparisonTab, true); 
        end

        % Value changed function: selectTimestepSecondary, 
        % y1AxisSecondaryComparisonTab, y2AxisSecondaryComparisonTab
        function graphComparisonTabReload_Secondary(app, event)
           
            y1Selection = app.y1AxisSecondaryComparisonTab.Value;
            y2Selection = app.y2AxisSecondaryComparisonTab.Value;
            timestepSelection = app.selectTimestepSecondary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoSecondary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphSecondaryComparisonTab, true); 

        end

        % Value changed function: xAxisPrimaryComparisonTab2, 
        % y1AxisPrimaryComparisonTab2, y2AxisPrimaryComparisonTab2
        function graphComparisonTab2Reload_Primary(app, event)
           
            xSelection = app.xAxisPrimaryComparisonTab2.Value;
            y1Selection = app.y1AxisPrimaryComparisonTab2.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab2.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphPrimaryComparisonTab2, true);             
        end

        % Value changed function: xAxisSecondaryComparisonTab2, 
        % y1AxisSecondaryComparisonTab2, 
        % y2AxisSecondaryComparisonTab2
        function graphComparisonTab2Reload_Secondary(app, event)
            xSelection = app.xAxisSecondaryComparisonTab2.Value;
            y1Selection = app.y1AxisSecondaryComparisonTab2.Value;
            y2Selection = app.y2AxisSecondaryComparisonTab2.Value;
            timestepSelection = app.selectTimestepSecondary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoSecondary, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphSecondaryComparisonTab2, true);   
            
        end

        % Button pushed function: ExportData2Excel
        function ExportData2ExcelButtonPushed(app, event)
            y1Selection = app.y1AxisPrimaryComparisonTab.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.export2csv(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection);   
            
        end

        % Button pushed function: btnExportGraphs
        function btnExportGraphsPushed(app, event)
            app.exportGraphs()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.triaxialExperimentResults = uifigure('Visible', 'off');
            app.triaxialExperimentResults.Name = 'Triaxial Experiments Analysis Tool';
            app.tabGroup = uitabgroup(app.triaxialExperimentResults);
            app.experimentsAndRockDataTab = uitab(app.tabGroup);
            app.experimentsAndRockDataTab.Title = 'Experiments and Rock Data';
            app.experimentOverviewPanel = uipanel(app.experimentsAndRockDataTab);
            app.experimentOverviewPanel.Title = 'Experiment Overview';
            app.experimentsOverviewTable = uitable(app.experimentOverviewPanel);
            app.experimentsOverviewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.experimentsOverviewTable.RowName = {};
            app.experimentsOverviewTable.ColumnSortable = true;
            app.SelectedExperimentLabel = uilabel(app.experimentOverviewPanel);
            app.SelectedExperimentLabel.Text = 'Selected Experiment:';
            app.selectExperimentPrimary = uidropdown(app.experimentOverviewPanel);
            app.selectExperimentPrimary.Items = {'- no selection -'};
            app.selectExperimentPrimary.ItemsData = {'0'};
            app.selectExperimentPrimary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentNoPrimary, true);
            app.selectExperimentPrimary.Value = '0';
            app.rockDataPanel = uipanel(app.experimentsAndRockDataTab);
            app.rockDataPanel.Title = 'Rock Data';
            app.rockDataTable = uitable(app.rockDataPanel);
            app.rockDataTable.ColumnName = {'Value'};
            app.rockDataTable.RowName = {''};
            app.dataAndOutliersTab = uitab(app.tabGroup);
            app.dataAndOutliersTab.Title = 'Data and Outliers';
            app.dataAndWeightPanel = uipanel(app.dataAndOutliersTab);
            app.dataAndWeightPanel.Title = 'All Data and Weight';
            app.graphOverview = uiaxes(app.dataAndWeightPanel);
            title(app.graphOverview, 'Title')
            xlabel(app.graphOverview, 'X')
            ylabel(app.graphOverview, 'Y')
            app.graphWeight = uiaxes(app.dataAndWeightPanel);
            title(app.graphWeight, 'Title')
            xlabel(app.graphWeight, 'X')
            ylabel(app.graphWeight, 'Y')
            app.fluidFlowAndDeformationPanel = uipanel(app.dataAndOutliersTab);
            app.fluidFlowAndDeformationPanel.Title = 'Fluid Pressure and deformation';
            app.graphFluidFlow = uiaxes(app.fluidFlowAndDeformationPanel);
            title(app.graphFluidFlow, 'Title')
            xlabel(app.graphFluidFlow, 'X')
            ylabel(app.graphFluidFlow, 'Y')
            app.graphDeformation = uiaxes(app.fluidFlowAndDeformationPanel);
            title(app.graphDeformation, 'Title')
            xlabel(app.graphDeformation, 'X')
            ylabel(app.graphDeformation, 'Y')
            app.timelogTab = uitab(app.tabGroup);
            app.timelogTab.Title = 'Results';
            app.permeabilityResultsPanel = uipanel(app.timelogTab);
            app.permeabilityResultsPanel.Title = 'Permeability results';
            app.graphTimelogTab = uiaxes(app.permeabilityResultsPanel);
            title(app.graphTimelogTab, '')
            xlabel(app.graphTimelogTab, {'Runtime (hh:mm)'; ''})
            ylabel(app.graphTimelogTab, {'Permeability'; ''})
            app.DisplayonrightaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonrightaxisLabel.Text = 'Display on right axis:';
            app.y2AxisTimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y2AxisTimelogTab.Items = {'- no selection -'};
            app.y2AxisTimelogTab.ItemsData = {'0'};
            app.y2AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @graphTimelogTabReload, true);
            app.y2AxisTimelogTab.Value = '0';
            app.clearEventsButton = uibutton(app.permeabilityResultsPanel, 'push');
            app.clearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @clearTimelogTicks, true);
            app.clearEventsButton.Enable = 'off';
            app.clearEventsButton.Text = 'Clear marked events';
            app.timelogPanel = uipanel(app.permeabilityResultsPanel);
            app.timelogPanel.Title = 'Timelog';
            app.timelogTableActual = uitable(app.timelogPanel);
            app.timelogTableActual.ColumnName = {'column 1'};
            app.timelogTableActual.RowName = {};
            app.timelogTableActual.ColumnEditable = [false false false true];
            app.timelogTableActual.CellEditCallback = createCallbackFcn(app, @timelogTicksChange, true);
            app.timelogTablePre = uitable(app.timelogPanel);
            app.timelogTablePre.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePre.RowName = {};
            app.timelogTablePost = uitable(app.timelogPanel);
            app.timelogTablePost.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePost.RowName = {};
            app.TimestepminLabel_2 = uilabel(app.permeabilityResultsPanel);
            app.TimestepminLabel_2.Text = 'Timestep (min):';
            app.selectTimestepPrimary = uidropdown(app.permeabilityResultsPanel);
            app.selectTimestepPrimary.Items = {'- no selection -'};
            app.selectTimestepPrimary.ItemsData = {'0'};
            app.selectTimestepPrimary.ValueChangedFcn = createCallbackFcn(app, @primaryTimestepChange, true);
            app.selectTimestepPrimary.Value = '0';
            app.DisplayonleftaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonleftaxisLabel.Text = 'Display on left axis:';
            app.y1AxisTimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y1AxisTimelogTab.Items = {'- no selection -'};
            app.y1AxisTimelogTab.ItemsData = {'0'};
            app.y1AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @graphTimelogTabReload, true);
            app.y1AxisTimelogTab.Value = '0';
            app.btnExportGraphs = uibutton(app.permeabilityResultsPanel, 'push');
            app.btnExportGraphs.ButtonPushedFcn = createCallbackFcn(app, @btnExportGraphsPushed, true);
            app.btnExportGraphs.Text = 'Export all graphs as shown';
            app.comparingResultsTab = uitab(app.tabGroup);
            app.comparingResultsTab.Title = 'Comparing Results';
            app.comparePanel = uipanel(app.comparingResultsTab);
            app.graphPrimaryComparisonTab = uiaxes(app.comparePanel);
            title(app.graphPrimaryComparisonTab, '')
            xlabel(app.graphPrimaryComparisonTab, 'X')
            ylabel(app.graphPrimaryComparisonTab, 'Y')
            app.graphSecondaryComparisonTab = uiaxes(app.comparePanel);
            title(app.graphSecondaryComparisonTab, '')
            xlabel(app.graphSecondaryComparisonTab, 'X')
            ylabel(app.graphSecondaryComparisonTab, 'Y')
            app.selectExperimentSecondary = uidropdown(app.comparePanel);
            app.selectExperimentSecondary.Items = {'- no selection -'};
            app.selectExperimentSecondary.ItemsData = {'0'};
            app.selectExperimentSecondary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentNoSecondary, true);
            app.selectExperimentSecondary.Value = '0';
            app.ComparetoExperimentNoLabel = uilabel(app.comparePanel);
            app.ComparetoExperimentNoLabel.Text = 'Compare to Experiment No:';
            app.TimestepminLabel = uilabel(app.comparePanel);
            app.TimestepminLabel.Text = 'Timestep (min):';
            app.selectTimestepSecondary = uidropdown(app.comparePanel);
            app.selectTimestepSecondary.Items = {'- no selection -'};
            app.selectTimestepSecondary.ItemsData = {'0'};
            app.selectTimestepSecondary.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Secondary, true);
            app.selectTimestepSecondary.Enable = 'off';
            app.selectTimestepSecondary.Value = '0';
            app.DisplayonrightaxisLabel_2 = uilabel(app.comparePanel);
            app.DisplayonrightaxisLabel_2.Text = 'Display on right axis';
            app.y2AxisPrimaryComparisonTab = uidropdown(app.comparePanel);
            app.y2AxisPrimaryComparisonTab.Items = {'- no selection -'};
            app.y2AxisPrimaryComparisonTab.ItemsData = {'0'};
            app.y2AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Primary, true);
            app.y2AxisPrimaryComparisonTab.Value = '0';
            app.DisplayonrightaxisLabel_3 = uilabel(app.comparePanel);
            app.DisplayonrightaxisLabel_3.Text = 'Display on right axis';
            app.y2AxisSecondaryComparisonTab = uidropdown(app.comparePanel);
            app.y2AxisSecondaryComparisonTab.Items = {'- no selection -'};
            app.y2AxisSecondaryComparisonTab.ItemsData = {'0'};
            app.y2AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Secondary, true);
            app.y2AxisSecondaryComparisonTab.Enable = 'off';
            app.y2AxisSecondaryComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDownLabel = uilabel(app.comparePanel);
            app.DisplayonleftaxisDropDownLabel.Text = 'Display on left axis';
            app.y1AxisPrimaryComparisonTab = uidropdown(app.comparePanel);
            app.y1AxisPrimaryComparisonTab.Items = {'- no selection -'};
            app.y1AxisPrimaryComparisonTab.ItemsData = {'0'};
            app.y1AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Primary, true);
            app.y1AxisPrimaryComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDown_2Label = uilabel(app.comparePanel);
            app.DisplayonleftaxisDropDown_2Label.Text = 'Display on left axis';
            app.y1AxisSecondaryComparisonTab = uidropdown(app.comparePanel);
            app.y1AxisSecondaryComparisonTab.Items = {'- no selection -'};
            app.y1AxisSecondaryComparisonTab.ItemsData = {'0'};
            app.y1AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Secondary, true);
            app.y1AxisSecondaryComparisonTab.Enable = 'off';
            app.y1AxisSecondaryComparisonTab.Value = '0';
            app.rockDataComparePagePrimary = uitable(app.comparePanel);
            app.rockDataComparePagePrimary.ColumnName = {'Property'; 'Value'};
            app.rockDataComparePagePrimary.RowName = {};
            app.rockDataComparePageSecondary = uitable(app.comparePanel);
            app.rockDataComparePageSecondary.ColumnName = {'Property'; 'Value'};
            app.rockDataComparePageSecondary.RowName = {};
            app.ExportData2Excel = uibutton(app.comparePanel, 'push');
            app.ExportData2Excel.ButtonPushedFcn = createCallbackFcn(app, @ExportData2ExcelButtonPushed, true);
            app.ExportData2Excel.Text = 'Export data to Excel';
            app.compare2Tab = uitab(app.tabGroup);
            app.compare2Tab.Title = 'Compare2';
            app.graphPrimaryComparisonTab2 = uiaxes(app.compare2Tab);
            title(app.graphPrimaryComparisonTab2, '')
            xlabel(app.graphPrimaryComparisonTab2, 'X')
            ylabel(app.graphPrimaryComparisonTab2, 'Y')
            app.graphSecondaryComparisonTab2 = uiaxes(app.compare2Tab);
            title(app.graphSecondaryComparisonTab2, '')
            xlabel(app.graphSecondaryComparisonTab2, 'X')
            ylabel(app.graphSecondaryComparisonTab2, 'Y')
            app.LeftAxisDropDownLabel = uilabel(app.compare2Tab);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.y1AxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y1AxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.xAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.xAxisDropDown_2Label.Text = 'x-Axis';
            app.xAxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.xAxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.RightAxisDropDownLabel = uilabel(app.compare2Tab);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.y2AxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y2AxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.LeftAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.LeftAxisDropDown_2Label.Text = 'Left Axis';
            app.y1AxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y1AxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.y1AxisSecondaryComparisonTab2.Enable = 'off';
            app.xAxisDropDownLabel = uilabel(app.compare2Tab);
            app.xAxisDropDownLabel.Text = 'x-Axis';
            app.xAxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.xAxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.xAxisSecondaryComparisonTab2.Enable = 'off';
            app.RightAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.RightAxisDropDown_2Label.Text = 'Right Axis';
            app.y2AxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y2AxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.y2AxisSecondaryComparisonTab2.Enable = 'off';
            % Show the figure after all components are created
            app.triaxialExperimentResults.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.triaxialExperimentResults)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.triaxialExperimentResults)
        end
    end
end